classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Label                          matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        PresetsTab_2                   matlab.ui.container.Tab
        AutoClassifySwitch             matlab.ui.control.Switch
        AutoClassifySwitchLabel        matlab.ui.control.Label
        ChooseModulationDropDown       matlab.ui.control.DropDown
        ChooseModulationDropDownLabel  matlab.ui.control.Label
        ChooseSNRDropDown              matlab.ui.control.DropDown
        ChooseSNRDropDownLabel         matlab.ui.control.Label
        ResultsTab                     matlab.ui.container.Tab
        BatchNum                       matlab.ui.control.Label
        BatchNumberLabel               matlab.ui.control.Label
        NextButton                     matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        PredictionLabel                matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        CurrentSignalLabel             matlab.ui.control.Label
        PredictionLamp_2               matlab.ui.control.Lamp
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ModulationClassifierRadioMLLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        IdxSNR
        Datastore 
        MBDatastore
        SamplesPerSNR
        SampleTicks
        Loop
        BatchNumber
        ModulationOptions
        SelectedModulation
        Stop
        SNROptions
        FilterModulation
        ModulationIdx
        SNRIdx
        StartIdx
        Stride
        CNNModel
    end
    
    methods (Access = private)
        % display signal and make prediction
        function predictBatch(app, batch)
            if (app.FilterModulation)
                plotData(app, batch, app.ModulationIdx)
            else
                for i = 1:11
                    % selectedIdx = app.SelectedModulation(i);
                    mod = app.SelectedModulation(i+1);
                    modIdx = find(strcmp(app.ModulationOptions, mod));

                    plotData(app, batch, modIdx-1)
                    % on interrupt
                    if app.Stop == true
                        break
                    end    
                end
            end
        end
        
        % plot signal on axes
        function plotData (app, batch, idx)
            % get data
            signal = batch{idx, 1};
            label = char(batch{idx, 2});

            % make prediction
            prediction = char(classify(app.CNNModel, signal));
            correct = strcmp(label, prediction);

            % initialize figure
            app.UIFigure

            % clear previous plot
            cla(app.UIAxes);


            % update current signal
            app.Label_2.Text = label;

            % plot data
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.SampleTicks, signal(:, :, 1), "LineWidth", 1);
            plot(app.UIAxes, app.SampleTicks, signal(:, :, 2), "LineWidth", 1);
            hold(app.UIAxes, 'off');
            ylim(app.UIAxes, [-6 6])
            xlim(app.UIAxes, [0 1024])
            grid(app.UIAxes, 'on');
            title(app.UIAxes, label);

            % update prediction label
            app.Label_3.Text = prediction;

            if (correct)
                app.PredictionLamp_2.Color = [0.00 1.00 0.00];
            else
                app.PredictionLamp_2.Color = [1.00 0.00 0.00];
            end

            % pause before next plot
            pause(2);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize variables
            app.SamplesPerSNR = 4096;
            app.IdxSNR = 65537;
            app.SampleTicks = linspace(0, 1023, 1024);
            app.BatchNumber = 1;
            app.Stop = false;
            app.FilterModulation = false;
            app.ModulationIdx = 0;
            app.SNRIdx = 1;
            app.StartIdx = app.SamplesPerSNR * (1+11) + 1 + 1000;

            % configure app behaviour
            app.Loop = "On";
            app.AutoClassifySwitch.Value = app.Loop;


            % initiate datastore
            app.Stride = app.SamplesPerSNR * 26 + 1;
            app.Datastore = RadioMLDatastore('./GOLD_XYZ_OSC.0001_1024.hdf5', "/X", "/Y", "test", 24, app.Stride, app.StartIdx);
            app.MBDatastore = MiniBatchDatastore(app.Datastore, 24);

            % set SNR options
            start_dB = 2;
            end_dB = 30;
            step_dB = 2;
            dB_values = start_dB:step_dB:end_dB;
            dB_strings = arrayfun(@(x) sprintf('%ddB', x), dB_values, 'UniformOutput', false);
            app.SNROptions = dB_strings;
            app.ChooseSNRDropDown.Items = app.SNROptions;

            % set Modulation options
            app.ModulationOptions = {...
                'All Modulations', ...
                'OOK', ...
                '4ASK', ...
                '8ASK', ...
                'BPSK', ...
                'QPSK', ...
                '8PSK', ...
                'PSK16', ...
                'PSK32', ...
                'APSK16', ...
                'APSK32', ...
                'APSK64', ...
                'APSK128', ...
                '16QAM', ...
                'QAM32', ...
                '64QAM', ...
                'QAM128', ...
                'QAM256', ...
                'AM-SSB-WC', ...
                'AM-SSB-SC', ...
                'AM-DSB-WC', ...
                'AM-DSB-SC', ...
                'FM', ...
                'GMSK', ...
                'OQPSK' ...
            };
            app.SelectedModulation = { ...
    'All Modulations', 'BPSK', 'QPSK', '16QAM', '64QAM', '8PSK', ...
    '4ASK', '8ASK', 'GMSK', 'AM-SSB-WC', 'AM-SSB-SC', 'FM' ...
            };
            app.ChooseModulationDropDown.Items = app.SelectedModulation;
            
            % load CNN model (91.8% accuracy)
            model2_91 = load("trained_model_small4.mat");
            model2_91 = model2_91.trained_model_small4;
            app.CNNModel = model2_91;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % start setup 
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";
            app.Stop = false;

            % main operations
            if app.Loop == "On"
                while true
                    batch = app.MBDatastore.read();
                    predictBatch(app, batch);
                    % on interrupt
                    if app.Stop == true
                        app.StartButton.Enable = "on";
                        break
                    end
                    % update
                    app.BatchNumber = app.BatchNumber + 1;
                    app.BatchNum.Text = sprintf("#%d", app.BatchNumber);
                end
            else
                batch = app.MBDatastore.read();
                predictBatch(app, batch); 
                app.StartButton.Enable = "on";
                title(app.UIAxes, "");
            end

        end

        % Value changed function: ChooseModulationDropDown
        function ChooseModulationDropDownValueChanged(app, event)
            value = app.ChooseModulationDropDown.Value;
            index = find(strcmp(app.ModulationOptions, value));
            index = index - 1;
            disp(index);
            if index > 0
                app.FilterModulation = true;
                app.ModulationIdx = index;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Stop = true;
            app.StopButton.Enable = "off";
        end

        % Value changed function: AutoClassifySwitch
        function AutoClassifySwitchValueChanged(app, event)
            value = app.AutoClassifySwitch.Value;
            app.Loop = value;
            disp(app.Loop)
        end

        % Value changed function: ChooseSNRDropDown
        function ChooseSNRDropDownValueChanged(app, event)
            value = app.ChooseSNRDropDown.Value;
            index = find(strcmp(app.SNROptions, value));
            disp(index);
            index = index - 1;
            app.StartIdx = app.SamplesPerSNR * (index+11) + 1 + 1000;

            % reintialize datastore
            app.Datastore = RadioMLDatastore('./GOLD_XYZ_OSC.0001_1024.hdf5', "/X", "/Y", "train", 24, app.Stride, app.StartIdx);
            app.MBDatastore = MiniBatchDatastore(app.Datastore, 24);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Samples')
            app.ModulationClassifierRadioMLLabel = uilabel(app.GridLayout);
            app.ModulationClassifierRadioMLLabel.Text = '  Modulation Classifier - RadioML';
            app.TabGroup3 = uitabgroup(app.GridLayout);
            app.PresetsTab_2 = uitab(app.TabGroup3);
            app.PresetsTab_2.Title = 'Presets';
            app.ChooseSNRDropDownLabel = uilabel(app.PresetsTab_2);
            app.ChooseSNRDropDownLabel.Text = 'Choose SNR';
            app.ChooseSNRDropDown = uidropdown(app.PresetsTab_2);
            app.ChooseSNRDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseSNRDropDownValueChanged, true);
            app.ChooseModulationDropDownLabel = uilabel(app.PresetsTab_2);
            app.ChooseModulationDropDownLabel.Text = 'Choose Modulation';
            app.ChooseModulationDropDown = uidropdown(app.PresetsTab_2);
            app.ChooseModulationDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseModulationDropDownValueChanged, true);
            app.AutoClassifySwitchLabel = uilabel(app.PresetsTab_2);
            app.AutoClassifySwitchLabel.Text = 'Auto Classify';
            app.AutoClassifySwitch = uiswitch(app.PresetsTab_2, 'slider');
            app.AutoClassifySwitch.ValueChangedFcn = createCallbackFcn(app, @AutoClassifySwitchValueChanged, true);
            app.ResultsTab = uitab(app.TabGroup3);
            app.ResultsTab.Title = 'Results';
            app.StartButton = uibutton(app.ResultsTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ResultsTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PredictionLamp_2 = uilamp(app.ResultsTab);
            app.CurrentSignalLabel = uilabel(app.ResultsTab);
            app.CurrentSignalLabel.Text = 'Current Signal';
            app.Label_2 = uilabel(app.ResultsTab);
            app.Label_2.Text = '-';
            app.PredictionLabel = uilabel(app.ResultsTab);
            app.PredictionLabel.Text = 'Prediction';
            app.Label_3 = uilabel(app.ResultsTab);
            app.Label_3.Text = '-';
            app.NextButton = uibutton(app.ResultsTab, 'push');
            app.NextButton.Text = 'Next';
            app.BatchNumberLabel = uilabel(app.ResultsTab);
            app.BatchNumberLabel.Text = 'Batch Number';
            app.BatchNum = uilabel(app.ResultsTab);
            app.BatchNum.Text = '#1';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end